classdef lcrgame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UITable                      matlab.ui.control.Table
        Dice3TextArea                matlab.ui.control.TextArea
        Dice3TextAreaLabel           matlab.ui.control.Label
        Dice2TextArea                matlab.ui.control.TextArea
        Dice2TextAreaLabel           matlab.ui.control.Label
        Dice1TextArea                matlab.ui.control.TextArea
        Dice1TextAreaLabel           matlab.ui.control.Label
        CenterPotTextArea            matlab.ui.control.TextArea
        CenterPotTextAreaLabel       matlab.ui.control.Label
        ISTHEREAWINNERTextArea       matlab.ui.control.TextArea
        ISTHEREAWINNERTextAreaLabel  matlab.ui.control.Label
        P3ScoreTextArea              matlab.ui.control.TextArea
        P3ScoreTextAreaLabel         matlab.ui.control.Label
        P2ScoreTextArea              matlab.ui.control.TextArea
        P2ScoreTextAreaLabel         matlab.ui.control.Label
        P1ScoreTextArea              matlab.ui.control.TextArea
        P1ScoreTextAreaLabel         matlab.ui.control.Label
        Image3                       matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        ReadyButton                  matlab.ui.control.Button
        ChoosePlayerButtonGroup      matlab.ui.container.ButtonGroup
        Player3Button                matlab.ui.control.RadioButton
        Player2Button                matlab.ui.control.RadioButton
        Player1Button                matlab.ui.control.RadioButton
        RollDiceButton               matlab.ui.control.Button
        Image4                       matlab.ui.control.Image
    end

    
    properties (Access = private)
        players;
        numPlayers;
        centerPot;
        turn;
        win;
        totalChips;
        myPlayerNum;
        readData;
        channelID = 1671438;
        writeKey = 'CBADFSRTGA24R1IO';
        readKey = 'W90S3DUEM5878NJE'; 
        channelID2 = 1673483;
        writeKey2 = '6J7QRIS7837JIDOQ';
        readKey2 = 'GGYYWJ9M7L2QJL1L';
        delay = 1;
        columns;
        rows;
        p1w = 0;
        p2w = 0;
        p3w = 0;
    end
    
    methods (Access = private)
        
        function [d1, d2, d3] = rollDice(app, i)
            if app.players.chips(i) >= 3
                d1 = randi([1 6],1,1);
                d2 = randi([1 6],1,1);
                d3 = randi([1 6],1,1);
            elseif app.players.chips(i) == 2
                d1 = randi([1 6],1,1);
                d2 = randi([1 6],1,1);
                d3 = 0;
            elseif app.players.chips(i) == 1
                d1 = randi([1 6],1,1);
                d2 = 0;
                d3 = 0;
            else
                d1 = 0; d2 = 0; d3 = 0;
            end
        end
        
        function determineScore(app, d1, d2, d3, i)
            diceRolls = [d1, d2 ,d3];
            for z=1:3
                j = i;
                if diceRolls(z) == 4
                    app.players.chips(j) = app.players.chips(j) - 1;
                    if ((j+1)>app.numPlayers)
                        j=1;
                        app.players.chips(j) = app.players.chips(j) + 1;
                    else
                        app.players.chips(j+1) = app.players.chips(j+1) + 1;
                    end
                end
                if diceRolls(z) == 5
                    app.players.chips(j) = app.players.chips(j) - 1;
                    app.centerPot = app.centerPot + 1;
                end
                if diceRolls(z) == 6
                    app.players.chips(j) = app.players.chips(j) - 1;
                    if ((j-1) < 1)
                        j = app.numPlayers;
                        app.players.chips(j) = app.players.chips(j) + 1;
                    else
                        app.players.chips(j-1) = app.players.chips(j-1) + 1;
                    end
                end
            end
            pause(app.delay);
            thingSpeakWrite(app.channelID, 'fields', [1:8], 'Values', [diceRolls(1), diceRolls(2), diceRolls(3), i, app.players.chips(1), app.players.chips(2), app.players.chips(3), app.centerPot], 'WriteKey', app.writeKey);
        end
        
        function endGame(app)
            
        end
        
        function updateScreen(app)
            checkWin(app);
            app.readData = thingSpeakRead(app.channelID);
            app.Dice1TextArea.Value = num2str(app.readData(1));
            app.Dice2TextArea.Value = num2str(app.readData(2));
            app.Dice3TextArea.Value = num2str(app.readData(3));
            app.P1ScoreTextArea.Value = num2str(app.readData(5));
            app.P2ScoreTextArea.Value = num2str(app.readData(6));
            app.P3ScoreTextArea.Value = num2str(app.readData(7));
            app.CenterPotTextArea.Value = num2str(app.readData(8));
            app.readData = thingSpeakRead(app.channelID2);
            if app.readData(1) == 0
                app.ISTHEREAWINNERTextArea.Value = sprintf("No winner.");
            else
                if app.readData(2) && (app.myPlayerNum == 1)
                    app.ISTHEREAWINNERTextArea.Value = sprintf("YOU WIN!");
                    set(app.RollDiceButton, 'Enable', 'off');
                    [y, Fs] = audioread('win.mp3');
                    variable = audioplayer(y, Fs);
                    play(variable);
                    pause(2);
                    pause(variable);
                    [y, Fs] = audioread('gameover.mp3');
                    variable = audioplayer(y, Fs);
                    play(variable);
                    pause(2);
                    pause(variable);
                elseif app.readData(3) && (app.myPlayerNum == 2)
                    app.ISTHEREAWINNERTextArea.Value = sprintf("YOU WIN!");
                    set(app.RollDiceButton, 'Enable', 'off');
                    [y, Fs] = audioread('win.mp3');
                    variable = audioplayer(y, Fs);
                    play(variable);
                    pause(2);
                    pause(variable);
                    [y, Fs] = audioread('gameover.mp3');
                    variable = audioplayer(y, Fs);
                    play(variable);
                    pause(2);
                    pause(variable);
                elseif app.readData(4) && (app.myPlayerNum == 3)
                    app.ISTHEREAWINNERTextArea.Value = sprintf("YOU WIN!");
                    set(app.RollDiceButton, 'Enable', 'off');
                    [y, Fs] = audioread('win.mp3');
                    variable = audioplayer(y, Fs);
                    play(variable);
                    pause(2);
                    pause(variable);
                    [y, Fs] = audioread('gameover.mp3');
                    variable = audioplayer(y, Fs);
                    play(variable);
                    pause(2);
                    pause(variable);
                else
                    app.ISTHEREAWINNERTextArea.Value = sprintf("You lose!");
                    set(app.RollDiceButton, 'Enable', 'off');
                    [y, Fs] = audioread('win.mp3');
                    variable = audioplayer(y, Fs);
                    play(variable);
                    pause(2);
                    pause(variable);
                    [y, Fs] = audioread('gameover.mp3');
                    variable = audioplayer(y, Fs);
                    play(variable);
                    pause(2);
                    pause(variable);
                end
            end
            app.readData = thingSpeakRead(app.channelID);
            if app.readData(1) == 1 
                app.Image.ImageSource = 'die1.jpg';
                app.Image.Visible = 'on';
            elseif app.readData(1)==2
                app.Image.ImageSource = imread('die2.jpg');
                app.Image.Visible = 'on';
            elseif app.readData(1)==3
                app.Image.ImageSource = imread('die3.jpg');
                app.Image.Visible = 'on';
            elseif app.readData(1)==4
                app.Image.ImageSource = imread('die4.jpg');
                app.Image.Visible = 'on';
            elseif app.readData(1)==5
                app.Image.ImageSource = imread('die5.jpg');
                app.Image.Visible = 'on';
            elseif app.readData(1)==6
                app.Image.ImageSource = imread('die6.jpg');
                app.Image.Visible = 'on';
            else
                app.Image.ImageSource = imread('die6.jpg');
                app.Image.Visible = 'off';
            end
            if app.readData(2) == 1 
                app.Image2.ImageSource = 'die1.jpg';
                app.Image2.Visible = 'on';
            elseif app.readData(2)==2
                app.Image2.ImageSource = imread('die2.jpg');
                app.Image2.Visible = 'on';
            elseif app.readData(2)==3
                app.Image2.ImageSource = imread('die3.jpg');
                app.Image2.Visible = 'on';
            elseif app.readData(2)==4
                app.Image2.ImageSource = imread('die4.jpg');
                app.Image2.Visible = 'on';
            elseif app.readData(2)==5
                app.Image2.ImageSource = imread('die5.jpg');
                app.Image2.Visible = 'on';
            elseif app.readData(2)==6
                app.Image2.ImageSource = imread('die6.jpg');
                app.Image2.Visible = 'on';
            else
               app.Image2.ImageSource = imread('die6.jpg');
               app.Image2.Visible = 'off';
            end
            if app.readData(3) == 1 
               app.Image3.ImageSource = 'die1.jpg';
               app.Image3.Visible = 'on';
            elseif app.readData(3)==2
               app.Image3.ImageSource = imread('die2.jpg');
               app.Image3.Visible = 'on';
            elseif app.readData(3)==3
               app.Image3.ImageSource = imread('die3.jpg');
               app.Image3.Visible = 'on';
            elseif app.readData(3)==4
               app.Image3.ImageSource = imread('die4.jpg');
               app.Image3.Visible = 'on';
            elseif app.readData(3)==5
               app.Image3.ImageSource = imread('die5.jpg');
               app.Image3.Visible = 'on';
            elseif app.readData(3)==6
               app.Image3.ImageSource = imread('die6.jpg');
               app.Image3.Visible = 'on';
            else
               app.Image3.ImageSource = imread('die6.jpg');
               app.Image3.Visible = 'off';
            end
        end

        function updateTable(app)
            a = [app.readData(1), app.readData(2), app.readData(3)];
            a = polyval(a,10);
            if rem(a,100) == 0
                a = a / 100;
            elseif rem(a,10) == 0
                a = a / 10;
            end
            app.rows = app.rows + 1;
            app.UITable.Data(app.rows,app.columns) =  a;
        end

        function updateChips(app)
            pause(app.delay);
            app.readData = thingSpeakRead(app.channelID);
            app.players.chips(1) = app.readData(5);
            app.players.chips(2) = app.readData(6);
            app.players.chips(3) = app.readData(7);
            app.centerPot = app.readData(8);
        end

        function checkWin(app)
            app.readData = thingSpeakRead(app.channelID2);
            if app.readData(1) == 1
                app.win = 1;
                app.p1w = app.readData(2);
                app.p2w = app.readData(3);
                app.p3w = app.readData(4);
                return;
            end
            app.readData = thingSpeakRead(app.channelID);
            temp = [app.readData(5), app.readData(6), app.readData(7)];
            temp2 = temp ~= 0;
            temp3 = temp(temp2);
            if length(temp3) == 1
                app.win = 1;
                if temp2(1) == 1
                    app.p1w = 1;
                elseif temp2(2) == 1
                    app.p2w = 1;
                else
                    app.p3w = 1;
                end
                pause(app.delay)
                thingSpeakWrite(app.channelID2, 'fields', [1:4], 'Values', [app.win,app.p1w,app.p2w,app.p3w], 'WriteKey', app.writeKey2);
            end
        end
        
        % Credit: Hansol K
        function [] = clearThingSpeakChannel(app)
            pause(app.delay);
            thingSpeakWrite(app.channelID, 'fields', [1:8], 'Values', [0, 0, 0, 0, 3, 3, 3, 0], 'WriteKey', app.writeKey);
            pause(app.delay);
            thingSpeakWrite(app.channelID2, 'fields', [1:4], 'Values', [0,0,0,0], 'WriteKey', app.writeKey2);
        end
        
        function [] = waitForP1Data(app)
            playerSent = 0;
            while playerSent ~= 1
                data = thingSpeakRead(app.channelID);
                playerSent = data(4);
                pause(app.delay);
            end
        end
        
        function [] = waitForP2Data(app)
            playerSent = 0;
            while playerSent ~= 2
                data = thingSpeakRead(app.channelID);
                playerSent = data(4);
                pause(app.delay);
            end
        end
        
        function [] = waitForP3Data(app)
            playerSent = 0;
            while playerSent ~= 3
                data = thingSpeakRead(app.channelID);
                playerSent = data(4);
                pause(app.delay);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.clearThingSpeakChannel()
            app.readData = thingSpeakRead(app.channelID2);
            app.players = struct("name", ["Player 1", "Player 2", "Player 3"], "chips", [3,3,3]);
            app.numPlayers = 3;
            app.myPlayerNum = 1;
            app.centerPot = 0;
            app.turn = 1;
            app.win = app.readData(1);
            app.totalChips = sum(app.players.chips);
            app.ISTHEREAWINNERTextArea.Value = sprintf('%s', string(app.win));
            set(app.RollDiceButton, 'Enable', 'off');
            app.UITable.Data = 0;
            app.columns = 1;
            app.rows = 0;
            updateScreen(app);
            app.Image.ImageSource = imread('die4.jpg');
            app.Image.Visible = 'on';
            app.Image2.ImageSource = imread('die5.jpg');
            app.Image2.Visible = 'on';
            app.Image3.ImageSource = imread('die6.jpg');
            app.Image3.Visible = 'on';
        end

        % Button pushed function: RollDiceButton
        function RollDiceButtonPushed(app, event)
            set(app.RollDiceButton, 'Enable', 'off');
            if app.myPlayerNum == 1
                % Update chips
                updateChips(app);
                % Roll dice
                [d1, d2, d3] = rollDice(app, 1);
                % Determine score
                determineScore(app, d1, d2, d3, 1);
                % Update screen
                updateScreen(app);
                updateTable(app);
                [y, Fs] = audioread('0004526.mp3');
                variable = audioplayer(y, Fs);
                play(variable);
                pause(5)
                pause(variable);
                app.waitForP2Data();
                updateScreen(app);
                app.waitForP3Data();
                updateScreen(app);
                pause(app.delay);
                if app.win == 0
                    set(app.RollDiceButton, 'Enable', 'on');
                end
            elseif app.myPlayerNum == 2
                % Update chips
                updateChips(app);
                % Roll dice
                [d1, d2, d3] = rollDice(app, 2);
                % Determine score
                determineScore(app, d1, d2, d3, 2);
                % Update screen
                updateScreen(app);
                updateTable(app);
                [y, Fs] = audioread('0004526.mp3');
                variable = audioplayer(y, Fs);
                play(variable);
                pause(5)
                pause(variable);
                app.waitForP3Data();
                updateScreen(app);
                app.waitForP1Data();
                updateScreen(app);
                pause(app.delay);
                if app.win == 0
                    set(app.RollDiceButton, 'Enable', 'on');
                end
            else
                % Update chips
                updateChips(app);
                % Roll dice
                [d1, d2, d3] = rollDice(app, 3);
                % Determine score
                determineScore(app, d1, d2, d3, 3);
                % Update screen
                updateScreen(app);
                updateTable(app);
                [y, Fs] = audioread('0004526.mp3');
                variable = audioplayer(y, Fs);
                play(variable);
                pause(5)
                pause(variable);
                app.waitForP1Data();
                updateScreen(app);
                app.waitForP2Data();
                updateScreen(app);
                pause(app.delay);
                if app.win == 0
                    set(app.RollDiceButton, 'Enable', 'on');
                end
            end
            % Determine if someone wins (make win = true)
            if app.win
                % End game
                
            else
                if app.turn == app.numPlayers
                    app.turn = 1;
                else
                    app.turn = app.turn + 1;
                end
            end
        end

        % Selection changed function: ChoosePlayerButtonGroup
        function ChoosePlayerButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChoosePlayerButtonGroup.SelectedObject;
            if selectedButton == app.Player1Button
                app.myPlayerNum = 1;
            elseif selectedButton == app.Player2Button
                app.myPlayerNum = 2;
            else
                app.myPlayerNum = 3;
            end
        end

        % Button pushed function: ReadyButton
        function ReadyButtonPushed(app, event)
            set(app.Player1Button, 'Enable', 'off');
            set(app.Player2Button, 'Enable', 'off');
            set(app.Player3Button, 'Enable', 'off');
            set(app.ReadyButton, 'Enable', 'off');
            if app.myPlayerNum == 1
                set(app.RollDiceButton, 'Enable', 'on');
            elseif app.myPlayerNum == 2
                app.waitForP1Data();
                app.readData = thingSpeakRead(app.channelID);
                updateScreen(app);
                set(app.RollDiceButton, 'Enable', 'on');
            else
                app.waitForP1Data();
                app.readData = thingSpeakRead(app.channelID);
                updateScreen(app);
                app.waitForP2Data();
                app.readData = thingSpeakRead(app.channelID);
                updateScreen(app);
                set(app.RollDiceButton, 'Enable', 'on');
            end
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'woodenbackground.jpg';
            app.RollDiceButton = uibutton(app.UIFigure, 'push');
            app.RollDiceButton.ButtonPushedFcn = createCallbackFcn(app, @RollDiceButtonPushed, true);
            app.RollDiceButton.Text = 'Roll Dice';
            app.ChoosePlayerButtonGroup = uibuttongroup(app.UIFigure);
            app.ChoosePlayerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChoosePlayerButtonGroupSelectionChanged, true);
            app.ChoosePlayerButtonGroup.Title = 'Choose Player #';
            app.Player1Button = uiradiobutton(app.ChoosePlayerButtonGroup);
            app.Player1Button.Text = 'Player 1';
            app.Player1Button.Value = true;
            app.Player2Button = uiradiobutton(app.ChoosePlayerButtonGroup);
            app.Player2Button.Text = 'Player 2';
            app.Player3Button = uiradiobutton(app.ChoosePlayerButtonGroup);
            app.Player3Button.Text = 'Player 3';
            app.ReadyButton = uibutton(app.UIFigure, 'push');
            app.ReadyButton.ButtonPushedFcn = createCallbackFcn(app, @ReadyButtonPushed, true);
            app.ReadyButton.Text = 'Ready';
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Image3 = uiimage(app.UIFigure);
            app.P1ScoreTextAreaLabel = uilabel(app.UIFigure);
            app.P1ScoreTextAreaLabel.Text = 'P1 Score';
            app.P1ScoreTextArea = uitextarea(app.UIFigure);
            app.P2ScoreTextAreaLabel = uilabel(app.UIFigure);
            app.P2ScoreTextAreaLabel.Text = 'P2 Score';
            app.P2ScoreTextArea = uitextarea(app.UIFigure);
            app.P3ScoreTextAreaLabel = uilabel(app.UIFigure);
            app.P3ScoreTextAreaLabel.Text = 'P3 Score';
            app.P3ScoreTextArea = uitextarea(app.UIFigure);
            app.ISTHEREAWINNERTextAreaLabel = uilabel(app.UIFigure);
            app.ISTHEREAWINNERTextAreaLabel.Text = 'IS THERE A WINNER';
            app.ISTHEREAWINNERTextArea = uitextarea(app.UIFigure);
            app.CenterPotTextAreaLabel = uilabel(app.UIFigure);
            app.CenterPotTextAreaLabel.Text = 'Center Pot';
            app.CenterPotTextArea = uitextarea(app.UIFigure);
            app.Dice1TextAreaLabel = uilabel(app.UIFigure);
            app.Dice1TextAreaLabel.Text = 'Dice 1';
            app.Dice1TextArea = uitextarea(app.UIFigure);
            app.Dice2TextAreaLabel = uilabel(app.UIFigure);
            app.Dice2TextAreaLabel.Text = 'Dice 2';
            app.Dice2TextArea = uitextarea(app.UIFigure);
            app.Dice3TextAreaLabel = uilabel(app.UIFigure);
            app.Dice3TextAreaLabel.Text = 'Dice 3';
            app.Dice3TextArea = uitextarea(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Rolls'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lcrgame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end